classdef kaoLightTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CalculateServoAnglesButton      matlab.ui.control.Button
        GridWidthmmEditFieldLabel       matlab.ui.control.Label
        GridWidthmmEditField            matlab.ui.control.NumericEditField
        GridHeightmmEditFieldLabel      matlab.ui.control.Label
        GridHeightmmEditField           matlab.ui.control.NumericEditField
        DistancefromSurfacemmEditFieldLabel  matlab.ui.control.Label
        DistancefromSurfacemmEditField  matlab.ui.control.NumericEditField
        TiltSpinnerLabel                matlab.ui.control.Label
        TiltSpinner                     matlab.ui.control.Spinner
        LaserToggleButton               matlab.ui.control.StateButton
        LaserSweepButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        pan_angles %stored values of the angles for the panning servo
        tilt_angles %stored values of the angles for the tilting servo
        digital_output = 0 %stores the current digital output value
    end
    
    methods (Access = private)
        
        %% functions to find the correct angles for a desired laser hit location
        %calculate the pan angle
        function [pan, D] = calcPan(app, B, distance_to_wall, commandPosition)
            pan = atand(commandPosition(1,1) / distance_to_wall);
            D = distance_to_wall / cosd(pan) - B;%Distance of Base of Tilt Platform to Wall (mm)
        end
        
        %calculate the tilt of the laser mount to hit a specific point
        function tilt = calcTilt(app, A, C, D, commandPosition)
            E = commandPosition(1, 2) - A; %find the vertical distace (on y) for the laser to hit a grid relative to where the base of the tilt arm is
            if E == A + C %if the laser need to hit parallel to the x-y plane, set the tilt angle to 90 degrees
                tilt = 90; %degrees
            else %if the laser is not parallel to the x-y plane, calculate the tilt angle
                coeffs = [C-D, -2 * E, D + C];
                angleRoots = roots(coeffs);
                tilt = max(2 * atand(angleRoots));  %degrees
            end
        end
        
        function [laserX, laserZ] = testCalc(app, tilt_angles, pan_angles, A, B, C, distance_to_wall)
            laserX = NaN(size(tilt_angles, 1), size(pan_angles, 2));
            laserZ = NaN(size(tilt_angles, 1), size(pan_angles, 2));
            for i = 1 : size(tilt_angles, 1)
                for j = 1 : size(pan_angles, 2)
                    tilt = tilt_angles(i, j);
                    pan = pan_angles(j);
                    [mount, laser] = applyRotation(app, tilt, pan, A, B, C, distance_to_wall);
        
                    laserX(i, j) = laser(1, 2); %keeps trace of where the laser is hitting the target
                    laserZ(i, j) = laser(3, 2); %keeps trace of where the laser is hitting the target
                end
            end
        end
        
        function [mount, laser] = applyRotation(app, tilt, pan, A, B, C, distance_to_wall)
            panAngle = pan; %degrees
            tiltAngle = 180 - tilt; %degrees
            D = distance_to_wall / cosd(pan) - B;%Distance of Base of Tilt Platform to Wall (mm)
        
            %find the length of the laser to the wall
            if tilt == 90
                laserLength = D;
            else
                laserLength = ((D + C / cosd(tilt)) / sind(tilt) - C * tand(tilt));
            end
        
            RL = xRot(app, -90); %set the rotation of the laser relative to the tilt arm to be perpendicular
            RT = xRot(app, tiltAngle); %find the local rotation of the tilt arm around the x axis
            RB = xRot(app, -90); %set the angle of the base of the tilt arm to be perpendicular to the pan arm
            RP = zRot(app, panAngle); %find the local (also global) rotation of the pan arm around the z axis
        
            panPos = [0; 0; A;]; %find the new end point position of the pan arm
            basePos = (RB * RP) \ [0; 0; B]; %find the new, untranslated position of the base of the tilt arm
            basePos = basePos + panPos; %translate the base of the tilt arm to the correct location
            tiltPos = (RT * RB * RP) \ [0; 0; C]; %find the new end point of the tilt arm before it is translated
            tiltPos = tiltPos + basePos; %translate the tilt arm to the correct global location
            laserPos = (RL * RT * RB * RP) \ [0; 0; laserLength]; %find the untranslated position of the laser
            laserPos = laserPos + tiltPos; %translate the laser to the correct, global position
        
            mount = [[0; 0; 0], panPos, basePos, tiltPos]; %combine all of the points of the laser mount into a matrix
            laser = [tiltPos, laserPos]; %combine the start and end points of the laser
        end
        
        %% Rotation Matrices Definitions
        %rotate around x axis
        function mat = xRot(app, theta)
        mat=[1 0 0
            0 cosd(theta) sind(theta)
            0 -sind(theta) cosd(theta)];
        end
        
        %rotate around y axis
        function mat = yRot(app, theta)
        mat=[cosd(theta) 0 -sind(theta)
            0 1 0
            sind(theta) 0 cosd(theta)];
        end
        
        %rotate around z axis
        function mat = zRot(app, theta)
        mat=[cosd(theta) sind(theta) 0
            -sind(theta) cosd(theta) 0
            0 0 1];
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateServoAnglesButton
        function CalculateServoAnglesButtonPushed(app, event)
            gird_width = app.GridWidthmmEditField.Value; %mm
            gird_height = app.GridHeightmmEditField.Value; %mm
            resolution = gird_width / (sqrt(400 / (gird_width / gird_height)) * (gird_width / gird_height)); %mm
            
            %segment lengths for the laser mount
            A = 41.17; %Panning Arm Length (mm)
            B = 24.78; %Base of Tilt Platform Length (mm)
            C = 33.32; %Tilt Arm Length (mm)
            distance_to_wall = app.DistancefromSurfacemmEditField.Value; %Distance of Base of Tilt Platform to Wall (mm)
            
            app.tilt_angles = NaN(round(gird_height/resolution), round(gird_width/resolution));
            app.pan_angles = NaN(1, round(gird_width/resolution));
            rowCount = 1;
            colCount = 1;
            for i = gird_height-resolution : -resolution : 0
                for j = (gird_width-resolution) / 2 : -resolution : -(gird_width-resolution) / 2
                    commandPosition = [j, i]; %set the command position
                    [pan, D] = calcPan(app, B, distance_to_wall, commandPosition); %find the pan angle and the distance of the base of the tilt platform to the wall
                    tilt = calcTilt(app, A, C, D, commandPosition); %find tilt angle
    
                    app.tilt_angles(rowCount, colCount) = tilt;
                    if rowCount == 1
                        app.pan_angles(colCount) = pan;
                    end
                    colCount = colCount + 1;
                end
                colCount = 1;
                rowCount = rowCount + 1;
            end
    
            [calculated_hits_x, calculated_hits_z] = testCalc(app, app.tilt_angles, app.pan_angles, A, B, C, distance_to_wall);
            
            size_tilt_angles = size(app.tilt_angles);
            app.TiltSpinner.Limits = [1 size_tilt_angles(1)];
            reshaped_tilt_angles = reshape(app.tilt_angles, [1, size_tilt_angles(1) * size_tilt_angles(2)]);
            
            labels = {'Save calculated x hits to a variable named:' ...
                      'Save calculated z hits to variable named:' ...
                      'Save calculated pan angles to a variable named' ...
                      'Save calculated tilt angles to a variable named' ...
                      'Save reshaped tilt angle array to a variable named'}; 
            vars = {'calculated_hits_x','calculated_hits_z', 'pan_angles', 'tilt_angles', 'reshaped_tilt_angles'}; 
            values = {calculated_hits_x, calculated_hits_z, app.pan_angles, app.tilt_angles, reshaped_tilt_angles};
            export2wsdlg(labels,vars,values);
        end

        % Value changed function: LaserToggleButton
        function LaserToggleButtonValueChanged(app, event)
            value = app.LaserToggleButton.Value;
            if value == 1
                xippmex('digout', 5, app.digital_output + 4096);
            else
                xippmex('digout', 5, app.digital_output);
            end
        end

        % Value changed function: TiltSpinner
        function TiltSpinnerValueChanged(app, event)
            value = app.TiltSpinner.Value;
            
            if size(app.tilt_angles) == 0
                error('You have not calculated the servo angles yet.')
            else
                size_tilt_angles = size(app.tilt_angles);
                app.digital_output = round(size_tilt_angles(2) / 2) + (value - 1) * size_tilt_angles(2);
                xippmex('digout', 5, app.digital_output);
            end
        end

        % Button pushed function: LaserSweepButton
        function LaserSweepButtonPushed(app, event)
            if size(app.tilt_angles) == 0
                error('You have not calculated the servo angles yet.')
            else
               for i = 1:length(app.pan_angles)
                   size_tilt_angles = size(app.tilt_angles);
                   app.digital_output = i + (app.TiltSpinner.Value - 1) * size_tilt_angles(2);
                   xippmex('digout', 5, app.digital_output);
               end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculateServoAnglesButton = uibutton(app.UIFigure, 'push');
            app.CalculateServoAnglesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateServoAnglesButtonPushed, true);
            app.CalculateServoAnglesButton.Text = 'Calculate Servo Angles';
            app.GridWidthmmEditFieldLabel = uilabel(app.UIFigure);
            app.GridWidthmmEditFieldLabel.Text = 'Grid Width (mm)';
            app.GridWidthmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.GridWidthmmEditField.Value = 300;
            app.GridHeightmmEditFieldLabel = uilabel(app.UIFigure);
            app.GridHeightmmEditFieldLabel.Text = 'Grid Height (mm)';
            app.GridHeightmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.GridHeightmmEditField.Value = 150;
            app.DistancefromSurfacemmEditFieldLabel = uilabel(app.UIFigure);
            app.DistancefromSurfacemmEditFieldLabel.Text = 'Distance from Surface (mm)';
            app.DistancefromSurfacemmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancefromSurfacemmEditField.Value = 300;
            app.TiltSpinnerLabel = uilabel(app.UIFigure);
            app.TiltSpinnerLabel.Text = 'Tilt';
            app.TiltSpinner = uispinner(app.UIFigure);
            app.TiltSpinner.Limits = [1 50];
            app.TiltSpinner.ValueChangedFcn = createCallbackFcn(app, @TiltSpinnerValueChanged, true);
            app.TiltSpinner.Value = 1;
            app.LaserToggleButton = uibutton(app.UIFigure, 'state');
            app.LaserToggleButton.ValueChangedFcn = createCallbackFcn(app, @LaserToggleButtonValueChanged, true);
            app.LaserToggleButton.Text = 'Laser Toggle';
            app.LaserSweepButton = uibutton(app.UIFigure, 'push');
            app.LaserSweepButton.ButtonPushedFcn = createCallbackFcn(app, @LaserSweepButtonPushed, true);
            app.LaserSweepButton.Text = 'Laser Sweep';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kaoLightTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end